classdef teddy_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TeddyMatlabUIFigure        matlab.ui.Figure
        Step3Panel                 matlab.ui.container.Panel
        ExportSTLButton            matlab.ui.control.Button
        Step2Panel                 matlab.ui.container.Panel
        ThicknessScaleSlider       matlab.ui.control.Slider
        ThicknessScaleSliderLabel  matlab.ui.control.Label
        Generate3DModelButton      matlab.ui.control.Button
        Step1Panel                 matlab.ui.container.Panel
        LoadBackgroundImageButton  matlab.ui.control.Button
        StartSketchButton          matlab.ui.control.Button
        LoadBinaryImageButton      matlab.ui.control.Button
        ModeDropDown               matlab.ui.control.DropDown
        ModeLabel                  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MIN_ROI_LENGTH = 15;
        roi = [];% ROI
        bdryVerts % vertices on the boundary
        binaryImgPath % binary image full path        
        backgroundImgLoaded        
        verts_ =[];
        newVerts_ =[];
        newTri_ =[];
        chordSpine_ =[];
        entryRow_ =[];
        entryCol_ =[];
        verts3D_ = []; 
        tri3D_ = [];
    end
    
    methods (Access = private)        
        function deleteUIAxesChildren(app)
            numChildren = length(app.UIAxes.Children);
            delete(app.UIAxes.Children(1:numChildren));
        end
        
        function resetUIAxes(app)            
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            app.UIAxes.Box = 'on';
            app.UIAxes.Position = [27 -1 959 969];  
            set(app.UIAxes,'View',[0 90]);
            rotate3d(app.UIAxes, 'off');
        end
        
        function enableRotation(app)
            rotate3d(app.UIAxes, 'on');
        end
        
        function resetROIandGenerate3D(app)
            % Reset ROI to empty
            app.roi = [];
            % Disable the button
            app.Generate3DModelButton.Enable = 'off';            
        end
        
        function deletePreviousSketch(app)
            numChildren = length(app.UIAxes.Children);
            for i = 1: numChildren
                type = convertCharsToStrings(app.UIAxes.Children(i).Type);
                if ( strcmp(type, "images.roi.freehand") )
                    delete(app.UIAxes.Children(1));      
                    return;
                end
            end
        end
        
        function [x,y] = scaleAxes(app, img)
            siz = size(img);
            
            xSize = siz(1);
            ySize = siz(2);
            maxSiz=max([xSize, ySize]);
            if (maxSiz > xSize)
                y = (0:1/ySize:1);
                y(end) = [];
                x = ((maxSiz-xSize)/2/maxSiz: 1/maxSiz: 1-(maxSiz-xSize)/2/maxSiz);
                x(end) = [];
            else
                x = (0:1/xSize:1);
                x(end) = [];
                y = ((maxSiz-ySize)/2/maxSiz: 1/maxSiz: 1-(maxSiz-ySize)/2/maxSiz);
                y(end) = [];
            end
        end
        
        function elevate(app, resetAxes)
            if (length(app.entryRow_) < 1 || length(app.entryCol_) < 1 )
                warndlg("ROI region too small, or not having enough edges. Try sketch again.");                
            else
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % 2. Elevate the pruned axis
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % number of segments in an elliptical arc
                numSeg = 10;
                
                % elevation scale
                elevScale = app.ThicknessScaleSlider.Value;
                
                DEBUG_ELEVATION = false;
                SHOW_FINAL_MESH = true;
                
                [ app.verts3D_, app.tri3D_] = Elevate ( numSeg, elevScale, app.verts_, app.newVerts_, app.newTri_, app.chordSpine_, app.entryRow_, app.entryCol_, app.UIAxes, DEBUG_ELEVATION, SHOW_FINAL_MESH);
                if (resetAxes)
                    resetUIAxes(app);
                end
                enableRotation(app);
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            clc;
            
            disableDefaultInteractivity(app.UIAxes);
            axis(app.UIAxes,'equal');
            value = app.ModeDropDown.Value;
            switch value
                case 'Plain Sketch'
                    % Gray out the "Load Binary Image" button
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='on';                    
            end
            app.Generate3DModelButton.Enable='off';
            app.ThicknessScaleSlider.Enable = 'off';
            app.ExportSTLButton.Enable = 'off';
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            deleteUIAxesChildren(app);
            resetUIAxes(app);
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='on';
                    
                case 'Overlapped Sketch'
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='on';
                    app.StartSketchButton.Enable='off';
                    app.backgroundImgLoaded = false;
                    
                case 'Load Binary Image'
                    app.LoadBinaryImageButton.Enable='on';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='off';
            end
            % Reset UI
            app.Generate3DModelButton.Enable = 'off';
            app.ThicknessScaleSlider.Enable = 'off';            
            app.ExportSTLButton.Enable = 'off';
            app.verts_ = [];
            app.newVerts_ =[];
            app.newTri_ = [];
            app.chordSpine_ = [];
            app.entryRow_ = [];
            app.entryCol_ = [];            
            
            resetROIandGenerate3D(app);
        end

        % Button pushed function: Generate3DModelButton
        function Generate3DModelButtonPushed(app, event)
            % Extract boundary vertices
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    if (length(app.roi) <= 0)
                        warndlg("No ROI is detected! Did you sketch an ROI?")
                        return;
                    end
                    set(app.roi, "InteractionsAllowed", "none");
                    app.bdryVerts = ExtractVertices(app.roi);
                case 'Overlapped Sketch'
                    if (length(app.roi) <= 0)
                        warndlg("No ROI is detected! Did you sketch an ROI?")
                        return;
                    end
                    set(app.roi, "InteractionsAllowed", "none");
                    app.bdryVerts = ExtractVertices(app.roi);
                case 'Load Binary Image'
                    app.bdryVerts = TraceBdry(app.binaryImgPath);
            end
            
            if (length(app.bdryVerts) < app.MIN_ROI_LENGTH)
                warndlg("ROI region too small, did you sketch large enough ROI?")
                return;
            end
            
            % Clean the canvas before proceeding
            deleteUIAxesChildren(app);
            resetUIAxes(app);
            % triangulation
            tri = Triangulation(app.bdryVerts);
            
            verts = app.bdryVerts;            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1. Create chordal axis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            DEBUG_CHORDAL_AXIS = false;
            [ chords, newTri, newVerts, entryRow, entryCol, chordSpine ] = constructChord ( tri, verts, DEBUG_CHORDAL_AXIS );
            
            app.verts_ = verts; 
            app.newVerts_ = newVerts; 
            app.newTri_ = newTri; 
            app.chordSpine_ = chordSpine; 
            app.entryRow_ = entryRow;
            app.entryCol_ = entryCol;
            
            resetAxes = true;
            elevate(app, resetAxes);
            
            resetROIandGenerate3D(app);
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    
                case 'Overlapped Sketch'                    
                    app.StartSketchButton.Enable='off';
                case 'Load Binary Image'
                    app.StartSketchButton.Enable='off';
            end
            
            if (~isempty(app.verts3D_) && ~isempty(app.tri3D_) )
                app.ExportSTLButton.Enable = 'on';
            end
        end

        % Button pushed function: StartSketchButton
        function StartSketchButtonPushed(app, event)
            % Reset UI
            app.Generate3DModelButton.Enable='off';
            app.ThicknessScaleSlider.Enable = 'off';
            app.ExportSTLButton.Enable = 'off';
            app.verts_ = [];
            app.newVerts_ =[];
            app.newTri_ = [];
            app.chordSpine_ = [];
            app.entryRow_ = [];
            app.entryCol_ = [];
            
            % Reset axes
            value = app.ModeDropDown.Value;
            switch value
                case 'Plain Sketch'
                    deleteUIAxesChildren(app);                    
                case 'Load Binary Image'
                    deleteUIAxesChildren(app);                    
                case 'Overlapped Sketch'
                    deletePreviousSketch(app);
            end
            resetUIAxes(app);
            
            % Reset roi
            resetROIandGenerate3D(app);
            
            app.roi = drawfreehand(app.UIAxes);
            
            if (~isempty(app.roi))
                pose=app.roi.Position;
                if (length(pose) > app.MIN_ROI_LENGTH)
                    app.Generate3DModelButton.Enable='on';
                    app.ThicknessScaleSlider.Enable = 'on';
                end
            end
        end

        % Button pushed function: LoadBackgroundImageButton
        function LoadBackgroundImageButtonPushed(app, event)
            deleteUIAxesChildren(app);
            resetUIAxes(app);
            app.Generate3DModelButton.Enable='off';
            app.ThicknessScaleSlider.Enable = 'off';
            app.ExportSTLButton.Enable = 'off';
            app.verts_ = [];
            app.newVerts_ =[];
            app.newTri_ = [];
            app.chordSpine_ = [];
            app.entryRow_ = [];
            app.entryCol_ = [];
            
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp'}, 'Select File to Open');
            if isequal(filename,0)
                return;
            end
            
            imgPath = [filepath, filename];
            img = imread(imgPath);
            [x,y] = scaleAxes(app, img);
            h=imagesc(y, x, img,'Parent',app.UIAxes);
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'on');
            set(h,'AlphaData',0.3);     
            app.backgroundImgLoaded = true;
            
            resetROIandGenerate3D(app);
            app.StartSketchButton.Enable='on';
        end

        % Button pushed function: LoadBinaryImageButton
        function LoadBinaryImageButtonPushed(app, event)
            % load from img
            deleteUIAxesChildren(app);
            resetUIAxes(app);
            
            app.Generate3DModelButton.Enable='off';
            app.ThicknessScaleSlider.Enable = 'off';
            app.ExportSTLButton.Enable = 'off';
            app.verts_ = [];
            app.newVerts_ =[];
            app.newTri_ = [];
            app.chordSpine_ = [];
            app.entryRow_ = [];
            app.entryCol_ = [];
            
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp'}, 'Select File to Open');
            if isequal(filename,0)
                return;
            end
            
            app.binaryImgPath = [filepath, filename];   
            img = imread(app.binaryImgPath);
            [x,y] = scaleAxes(app, img);
            h = imagesc(y, x, img,'Parent',app.UIAxes);
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'on');
            set(h,'AlphaData',0.3);
            
            app.Generate3DModelButton.Enable='on';
            app.ThicknessScaleSlider.Enable = 'on';
        end

        % Value changed function: ThicknessScaleSlider
        function ThicknessScaleSliderValueChanged(app, event)
            if (isempty(app.verts_) || isempty(app.newVerts_) || isempty(app.newTri_) || isempty(app.chordSpine_) || isempty(app.entryRow_) || isempty(app.entryCol_))
                return
            end
            resetAxes = false;
            deleteUIAxesChildren(app);
            elevate(app, resetAxes);
            
            if (~isempty(app.verts3D_) && ~isempty(app.tri3D_) )
                app.ExportSTLButton.Enable = 'on';
            end
            
            resetROIandGenerate3D(app);
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    
                case 'Overlapped Sketch'                    
                    app.StartSketchButton.Enable='off';
                case 'Load Binary Image'
                    app.StartSketchButton.Enable='off';
            end
        end

        % Button pushed function: ExportSTLButton
        function ExportSTLButtonPushed(app, event)
            [file, location] = uiputfile('*.stl','Export to *.stl file','output.stl');
            if ~isequal(file,0) && ~isequal(location,0)
                saveFilePath = fullfile(location, file);
            else
                return;
            end
            
            Write2Stl(app.verts3D_, app.tri3D_, saveFilePath );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TeddyMatlabUIFigure = uifigure('Visible', 'off');
            app.TeddyMatlabUIFigure.Name = 'Teddy - Matlab';
            app.TeddyMatlabUIFigure.Icon = 'logo3-2.png';
            app.UIAxes = uiaxes(app.TeddyMatlabUIFigure);
            title(app.UIAxes, 'Sketch Area')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.Step1Panel = uipanel(app.TeddyMatlabUIFigure);
            app.Step1Panel.Title = 'Step1';
            app.ModeLabel = uilabel(app.Step1Panel);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.Step1Panel);
            app.ModeDropDown.Items = {'Plain Sketch', 'Overlapped Sketch', 'Load Binary Image'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Plain Sketch';
            app.LoadBinaryImageButton = uibutton(app.Step1Panel, 'push');
            app.LoadBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBinaryImageButtonPushed, true);
            app.LoadBinaryImageButton.Text = 'Load Binary Image';
            app.StartSketchButton = uibutton(app.Step1Panel, 'push');
            app.StartSketchButton.ButtonPushedFcn = createCallbackFcn(app, @StartSketchButtonPushed, true);
            app.StartSketchButton.Text = 'Start Sketch';
            app.LoadBackgroundImageButton = uibutton(app.Step1Panel, 'push');
            app.LoadBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBackgroundImageButtonPushed, true);
            app.LoadBackgroundImageButton.Text = 'Load Background Image';
            app.Step2Panel = uipanel(app.TeddyMatlabUIFigure);
            app.Step2Panel.Title = 'Step2';
            app.Generate3DModelButton = uibutton(app.Step2Panel, 'push');
            app.Generate3DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Generate3DModelButtonPushed, true);
            app.Generate3DModelButton.Text = 'Generate 3D Model';
            app.ThicknessScaleSliderLabel = uilabel(app.Step2Panel);
            app.ThicknessScaleSliderLabel.Text = {'Thickness'; 'Scale'};
            app.ThicknessScaleSlider = uislider(app.Step2Panel);
            app.ThicknessScaleSlider.Limits = [0.5 2.5];
            app.ThicknessScaleSlider.MajorTicks = [0.5 1 1.5 2 2.5];
            app.ThicknessScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessScaleSliderValueChanged, true);
            app.ThicknessScaleSlider.MinorTicks = [0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5];
            app.ThicknessScaleSlider.Value = 1.3;
            app.Step3Panel = uipanel(app.TeddyMatlabUIFigure);
            app.Step3Panel.Title = 'Step3';
            app.ExportSTLButton = uibutton(app.Step3Panel, 'push');
            app.ExportSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSTLButtonPushed, true);
            app.ExportSTLButton.Text = 'Export STL';
            % Show the figure after all components are created
            app.TeddyMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teddy_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TeddyMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TeddyMatlabUIFigure)
        end
    end
end